classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        InstructionsPanel         matlab.ui.container.Panel
        EdittheLabel_3            matlab.ui.control.Label
        EdittheLabel_2            matlab.ui.control.Label
        EdittheLabel              matlab.ui.control.Label
        EditExperimentPanel       matlab.ui.container.Panel
        VisiblePlotsSlider        matlab.ui.control.Slider
        VisiblePlotsSliderLabel   matlab.ui.control.Label
        FontColourDropDown        matlab.ui.control.DropDown
        FontColourDropDownLabel   matlab.ui.control.Label
        FontSizeSlider            matlab.ui.control.Slider
        FontSizeSliderLabel       matlab.ui.control.Label
        ShapeWidthSlider          matlab.ui.control.Slider
        ShapeWidthSliderLabel     matlab.ui.control.Label
        BoardNumberDropDown       matlab.ui.control.DropDown
        BoardNumberDropDownLabel  matlab.ui.control.Label
        BoardWidthSlider          matlab.ui.control.Slider
        BoardWidthSliderLabel     matlab.ui.control.Label
        NumberofsimmilarneedleshighlightedDropDown  matlab.ui.control.DropDown
        NumberofsimmilarneedleshighlightedDropDownLabel  matlab.ui.control.Label
        RunExperimentPanel        matlab.ui.container.Panel
        AverageLabel              matlab.ui.control.Label
        PiLabel                   matlab.ui.control.Label
        RunExperimentButton       matlab.ui.control.Button
        Throws                    matlab.ui.control.DropDown
        ThrowsLabel               matlab.ui.control.Label
        ExperimentDropDown        matlab.ui.control.DropDown
        ExperimentDropDownLabel   matlab.ui.control.Label
        UIAxes                    matlab.ui.control.UIAxes
    end

    % To Add properties use green plus under properties on right side of screen
    % Keeps track of average and count for each experiment
    properties (Access = private)
        NeedleValue;
        SquareValuePi;
        SquareValueRoot;

        CurrentAverageNeedle = 0;
        CountNeedle = 0;

        CurrentAverageTriangle = 0;
        CountTriangle = 0;

        CurrentAverageSquare = 0;
        CountSquare = 0;     

        CurrentAverageRoot = 0;
        CountRoot = 0;
    end
    
    methods (Access = private)
        

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunExperimentButton
        function Task_1_Button(app, event)
            % Runs the experiments and passes in arguments from buttons/menus allowing width, length, throws, boards, shapes to be controlled
            % Gives app.uiaxes hadndel to scripts which lets the external function know where to plot the data (in the GUI)
            % Caluclates an average for each type of experiment, using external propeties to keep track of them
            if app.ExperimentDropDown.Value == 1
                [pi, crossing] = BuffonStandardsPiPlot(app.BoardWidthSlider.Value, app.ShapeWidthSlider.Value, app.Throws.Value, app.UIAxes, app.BoardNumberDropDown.Value, app.NumberofsimmilarneedleshighlightedDropDown.Value, round(app.VisiblePlotsSlider.Value));
                app.PiLabel.Text = ['Pi (Needles):  ' num2str(pi)];
                average = ((app.CurrentAverageNeedle * app.CountNeedle) + pi) / (app.CountNeedle + 1);
                app.CountNeedle = app.CountNeedle + 1;
                app.CurrentAverageNeedle = average;
                app.AverageLabel.Text = ['Average value:  ' num2str(average)];

            elseif app.ExperimentDropDown.Value == 2
                [pi, crossing] = BuffonTrianglesPiPlot(app.BoardWidthSlider.Value, app.ShapeWidthSlider.Value, app.Throws.Value, app.UIAxes, app.BoardNumberDropDown.Value, round(app.VisiblePlotsSlider.Value));
                app.PiLabel.Text = ['Pi (Triangles):  ' num2str(pi)]; 
                average = ((app.CurrentAverageTriangle * app.CountTriangle) + pi) / (app.CountTriangle + 1);
                app.CountTriangle = app.CountTriangle + 1;
                app.CurrentAverageTriangle = average;
                app.AverageLabel.Text = ['Average value:  ' num2str(average)]; 

            elseif app.ExperimentDropDown.Value == 3
                [pi, crossing] = BuffonSquaresPiPlot(app.BoardWidthSlider.Value, app.ShapeWidthSlider.Value, app.Throws.Value, app.UIAxes, app.BoardNumberDropDown.Value, round(app.VisiblePlotsSlider.Value));
                app.PiLabel.Text = ['Pi (Squares):  ' num2str(pi)];  
                average = ((app.CurrentAverageSquare * app.CountSquare) + pi) / (app.CountSquare + 1);
                app.CountSquare = app.CountSquare + 1;
                app.CurrentAverageSquare = average;
                app.AverageLabel.Text = ['Average value:  ' num2str(average)]; 

            elseif app.ExperimentDropDown.Value == 4
                [r, crossing] = BuffonSquaresRootTwoPlot(app.BoardWidthSlider.Value, app.ShapeWidthSlider.Value, app.Throws.Value, app.UIAxes, app.BoardNumberDropDown.Value, round(app.VisiblePlotsSlider.Value));
                app.PiLabel.Text = ['Root 2 (Squares):  ' num2str(r)];  
                average = ((app.CurrentAverageRoot * app.CountRoot) + r) / (app.CountRoot + 1);
                app.CountRoot = app.CountRoot + 1;
                app.CurrentAverageRoot = average;
                app.AverageLabel.Text = ['Average value:  ' num2str(average)]; 
            end
        end

        % Value changed function: FontSizeSlider
        function FontSizeChange(app, event)
            % Uses slider to control font size of result
            value = app.FontSizeSlider.Value;
            app.PiLabel.FontSize = value;
        end

        % Value changed function: FontColourDropDown
        function ColourChange(app, event)
            % Uses menu to control colour of the result
            value = app.FontColourDropDown.Value;
            app.PiLabel.FontColor = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Experiment')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y  ')
            zlabel(app.UIAxes, 'Z')
            app.RunExperimentPanel = uipanel(app.UIFigure);
            app.RunExperimentPanel.Title = 'Run Experiment';
            app.ExperimentDropDownLabel = uilabel(app.RunExperimentPanel);
            app.ExperimentDropDownLabel.Text = 'Experiment';
            app.ExperimentDropDown = uidropdown(app.RunExperimentPanel);
            app.ExperimentDropDown.Items = {'Needles Pi', 'Triangles Pi', 'Squares Pi', 'Squares Root 2'};
            app.ExperimentDropDown.ItemsData = [1 2 3 4];
            app.ExperimentDropDown.Value = 1;
            app.ThrowsLabel = uilabel(app.RunExperimentPanel);
            app.ThrowsLabel.Text = 'Throws';
            app.Throws = uidropdown(app.RunExperimentPanel);
            app.Throws.Items = {'500,000', '1,000,000', '10,000,000'};
            app.Throws.ItemsData = [500000 1000000 10000000];
            app.Throws.Value = 500000;
            app.RunExperimentButton = uibutton(app.RunExperimentPanel, 'push');
            app.RunExperimentButton.ButtonPushedFcn = createCallbackFcn(app, @Task_1_Button, true);
            app.RunExperimentButton.Text = 'Run Experiment';
            app.PiLabel = uilabel(app.RunExperimentPanel);
            app.PiLabel.Text = 'Result:';
            app.AverageLabel = uilabel(app.RunExperimentPanel);
            app.AverageLabel.Text = 'Average:';
            app.EditExperimentPanel = uipanel(app.UIFigure);
            app.EditExperimentPanel.Title = 'Edit Experiment';
            app.NumberofsimmilarneedleshighlightedDropDownLabel = uilabel(app.EditExperimentPanel);
            app.NumberofsimmilarneedleshighlightedDropDownLabel.Text = 'Number of simmilar needles highlighted';
            app.NumberofsimmilarneedleshighlightedDropDown = uidropdown(app.EditExperimentPanel);
            app.NumberofsimmilarneedleshighlightedDropDown.Items = {'1', '2', '3', '4', '5', '6', '7', '8'};
            app.NumberofsimmilarneedleshighlightedDropDown.ItemsData = [1 2 3 4 5 6 7 8];
            app.NumberofsimmilarneedleshighlightedDropDown.Value = 1;
            app.BoardWidthSliderLabel = uilabel(app.EditExperimentPanel);
            app.BoardWidthSliderLabel.Text = 'Board Width';
            app.BoardWidthSlider = uislider(app.EditExperimentPanel);
            app.BoardWidthSlider.Limits = [2 10];
            app.BoardWidthSlider.MinorTicks = [];
            app.BoardWidthSlider.Value = 2;
            app.BoardNumberDropDownLabel = uilabel(app.EditExperimentPanel);
            app.BoardNumberDropDownLabel.Text = 'Board Number';
            app.BoardNumberDropDown = uidropdown(app.EditExperimentPanel);
            app.BoardNumberDropDown.Items = {'2', '3', '4', '5', '6', '7', '8', '9', '10'};
            app.BoardNumberDropDown.ItemsData = [2 3 4 5 6 7 8 9 10];
            app.BoardNumberDropDown.Value = 2;
            app.ShapeWidthSliderLabel = uilabel(app.EditExperimentPanel);
            app.ShapeWidthSliderLabel.Text = 'Shape Width';
            app.ShapeWidthSlider = uislider(app.EditExperimentPanel);
            app.ShapeWidthSlider.Limits = [0.1 1];
            app.ShapeWidthSlider.Value = 1;
            app.FontSizeSliderLabel = uilabel(app.EditExperimentPanel);
            app.FontSizeSliderLabel.Text = 'Font Size';
            app.FontSizeSlider = uislider(app.EditExperimentPanel);
            app.FontSizeSlider.Limits = [9 26];
            app.FontSizeSlider.ValueChangedFcn = createCallbackFcn(app, @FontSizeChange, true);
            app.FontSizeSlider.Value = 12;
            app.FontColourDropDownLabel = uilabel(app.EditExperimentPanel);
            app.FontColourDropDownLabel.Text = 'Font Colour';
            app.FontColourDropDown = uidropdown(app.EditExperimentPanel);
            app.FontColourDropDown.Items = {'White', 'Red', 'Green', 'Blue'};
            app.FontColourDropDown.ValueChangedFcn = createCallbackFcn(app, @ColourChange, true);
            app.FontColourDropDown.Value = 'White';
            app.VisiblePlotsSliderLabel = uilabel(app.EditExperimentPanel);
            app.VisiblePlotsSliderLabel.Text = 'Visible Plots';
            app.VisiblePlotsSlider = uislider(app.EditExperimentPanel);
            app.VisiblePlotsSlider.Limits = [0 1000];
            app.VisiblePlotsSlider.Value = 200;
            app.InstructionsPanel = uipanel(app.UIFigure);
            app.InstructionsPanel.Title = 'Instructions';
            app.EdittheLabel = uilabel(app.InstructionsPanel);
            app.EdittheLabel.Text = '- Edit the experiment settings before running the experiment for them to be applied ';
            app.EdittheLabel_2 = uilabel(app.InstructionsPanel);
            app.EdittheLabel_2.Text = '- Blue shapes are normal, green shapes cross a crack, needles turn yellow when clicked on and the simmilar needles turn purple';
            app.EdittheLabel_3 = uilabel(app.InstructionsPanel);
            app.EdittheLabel_3.Text = '- The Average reult of all of the last previous runs of the current selected experiment is shown below the result';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end